classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotPanel                     matlab.ui.container.Panel
        Image3                        matlab.ui.control.Image
        TextArea                      matlab.ui.control.TextArea
        SlicesButton                  matlab.ui.control.Button
        StructureButton               matlab.ui.control.Button
        ModelButton                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        AdaptiveSlicingTab            matlab.ui.container.Tab
        SettingsPanel                 matlab.ui.container.Panel
        STLfileDropDownLabel          matlab.ui.control.Label
        STLfileDropDown               matlab.ui.control.DropDown
        IncrementsEditFieldLabel      matlab.ui.control.Label
        IncrementsEditField           matlab.ui.control.NumericEditField
        CustomFileButton              matlab.ui.control.Button
        SimpleButton                  matlab.ui.control.Button
        AdaptiveButton                matlab.ui.control.Button
        AdaptiveSlicingPanel          matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        CurvedLayerSlicingTab         matlab.ui.container.Tab
        SettingsPanel_2               matlab.ui.container.Panel
        STLfileDropDown_2Label        matlab.ui.control.Label
        STLfileDropDown_2             matlab.ui.control.DropDown
        IncrementsEditField_2Label    matlab.ui.control.Label
        IncrementsEditField_2         matlab.ui.control.NumericEditField
        CustomFileButton_2            matlab.ui.control.Button
        CurvedLayerSettingsPanel      matlab.ui.container.Panel
        NoofXEditFieldLabel           matlab.ui.control.Label
        NoofXEditField                matlab.ui.control.NumericEditField
        CreatefreeformButton          matlab.ui.control.Button
        NoofYEditFieldLabel           matlab.ui.control.Label
        NoofYEditField                matlab.ui.control.NumericEditField
        NoofLayersEditFieldLabel      matlab.ui.control.Label
        NoofLayersEditField           matlab.ui.control.NumericEditField
        SliceThicknessEditFieldLabel  matlab.ui.control.Label
        SliceThicknessEditField       matlab.ui.control.NumericEditField
        CurvedLayerSliceButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
    % Variables for GUI
    img
    file = "Cylinder.stl"
    increments = 1;
    % Todo, grab below values from UI tble
    thicknessArray = {0,0.1,0.2,0.5,1.0};
    angleArray = {90, 85, 79, 65, 0};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('AdaptiveParameters.xlsx');
            
            app.UITable.Data = t;
            app.UITable.ColumnEditable = true;
        end

        % Button pushed function: CustomFileButton
        function CustomFileButtonPushed(app, event)
            app.file = "temp/" + uigetfile;
        end

        % Button pushed function: SimpleButton
        function SimpleButtonPushed(app, event)
            Slicing("Models/" + app.file, app.increments);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
        end

        % Button pushed function: SlicesButton
        function SlicesButtonPushed(app, event)
            app.img = imread("slicing2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: StructureButton
        function StructureButtonPushed(app, event)
            app.img = imread("structure2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.img = imread("model2.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Value changed function: STLfileDropDown
        function STLfileDropDownValueChanged(app, event)
            value = app.STLfileDropDown.Value;
            app.file = value + ".stl";
        end

        % Value changed function: IncrementsEditField
        function IncrementsEditFieldValueChanged(app, event)
            value = app.IncrementsEditField.Value;
            app.increments = value;
        end

        % Button pushed function: AdaptiveButton
        function AdaptiveButtonPushed(app, event)

            AdaptiveSlicingScript("Models/" + app.file, app.increments, app.thicknessArray, app.angleArray);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Adaptive Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.Image3 = uiimage(app.PlotPanel);
            app.Image3.ImageSource = 'default.jpg';
            app.TextArea = uitextarea(app.PlotPanel);
            app.SlicesButton = uibutton(app.PlotPanel, 'push');
            app.SlicesButton.ButtonPushedFcn = createCallbackFcn(app, @SlicesButtonPushed, true);
            app.SlicesButton.Text = 'Slices';
            app.StructureButton = uibutton(app.PlotPanel, 'push');
            app.StructureButton.ButtonPushedFcn = createCallbackFcn(app, @StructureButtonPushed, true);
            app.StructureButton.Text = 'Structure';
            app.ModelButton = uibutton(app.PlotPanel, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Text = 'Model';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdaptiveSlicingTab = uitab(app.TabGroup);
            app.AdaptiveSlicingTab.Title = 'Adaptive Slicing';
            app.SettingsPanel = uipanel(app.AdaptiveSlicingTab);
            app.SettingsPanel.Title = 'Settings';
            app.STLfileDropDownLabel = uilabel(app.SettingsPanel);
            app.STLfileDropDownLabel.Text = {'STL file'; ''};
            app.STLfileDropDown = uidropdown(app.SettingsPanel);
            app.STLfileDropDown.Items = {'Cylinder', 'Pyramid', 'Cube'};
            app.STLfileDropDown.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDownValueChanged, true);
            app.STLfileDropDown.Value = 'Cylinder';
            app.IncrementsEditFieldLabel = uilabel(app.SettingsPanel);
            app.IncrementsEditFieldLabel.Text = 'Increments';
            app.IncrementsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.IncrementsEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementsEditFieldValueChanged, true);
            app.IncrementsEditField.Value = 1;
            app.CustomFileButton = uibutton(app.SettingsPanel, 'push');
            app.CustomFileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButtonPushed, true);
            app.CustomFileButton.Text = 'Custom File';
            app.SimpleButton = uibutton(app.SettingsPanel, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleButtonPushed, true);
            app.SimpleButton.Text = 'Simple';
            app.AdaptiveButton = uibutton(app.SettingsPanel, 'push');
            app.AdaptiveButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveButtonPushed, true);
            app.AdaptiveButton.Text = 'Adaptive';
            app.AdaptiveSlicingPanel = uipanel(app.AdaptiveSlicingTab);
            app.AdaptiveSlicingPanel.Title = 'Adaptive Slicing';
            app.UITable = uitable(app.AdaptiveSlicingTab);
            app.UITable.ColumnName = {'Min Angle'; 'Max Angle'; 'Min Residue'; 'Max Residue'; 'Thickness'};
            app.UITable.RowName = {};
            app.CurvedLayerSlicingTab = uitab(app.TabGroup);
            app.CurvedLayerSlicingTab.Title = 'Curved Layer Slicing';
            app.SettingsPanel_2 = uipanel(app.CurvedLayerSlicingTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.STLfileDropDown_2Label = uilabel(app.SettingsPanel_2);
            app.STLfileDropDown_2Label.Text = {'STL file'; ''};
            app.STLfileDropDown_2 = uidropdown(app.SettingsPanel_2);
            app.STLfileDropDown_2.Items = {'Cylinder', 'Pyramid', 'Cube'};
            app.STLfileDropDown_2.Value = 'Cylinder';
            app.IncrementsEditField_2Label = uilabel(app.SettingsPanel_2);
            app.IncrementsEditField_2Label.Text = 'Increments';
            app.IncrementsEditField_2 = uieditfield(app.SettingsPanel_2, 'numeric');
            app.IncrementsEditField_2.Value = 1;
            app.CustomFileButton_2 = uibutton(app.SettingsPanel_2, 'push');
            app.CustomFileButton_2.Text = 'Custom File';
            app.CurvedLayerSettingsPanel = uipanel(app.CurvedLayerSlicingTab);
            app.CurvedLayerSettingsPanel.Title = 'Curved Layer Settings';
            app.NoofXEditFieldLabel = uilabel(app.CurvedLayerSettingsPanel);
            app.NoofXEditFieldLabel.Text = 'No. of X';
            app.NoofXEditField = uieditfield(app.CurvedLayerSettingsPanel, 'numeric');
            app.NoofXEditField.Value = 10;
            app.CreatefreeformButton = uibutton(app.CurvedLayerSettingsPanel, 'push');
            app.CreatefreeformButton.Text = 'Create free form';
            app.NoofYEditFieldLabel = uilabel(app.CurvedLayerSettingsPanel);
            app.NoofYEditFieldLabel.Text = 'No. of Y';
            app.NoofYEditField = uieditfield(app.CurvedLayerSettingsPanel, 'numeric');
            app.NoofYEditField.Value = 10;
            app.NoofLayersEditFieldLabel = uilabel(app.CurvedLayerSettingsPanel);
            app.NoofLayersEditFieldLabel.Text = 'No. of Layers';
            app.NoofLayersEditField = uieditfield(app.CurvedLayerSettingsPanel, 'numeric');
            app.NoofLayersEditField.Value = 5;
            app.SliceThicknessEditFieldLabel = uilabel(app.CurvedLayerSettingsPanel);
            app.SliceThicknessEditFieldLabel.Text = 'Slice Thickness';
            app.SliceThicknessEditField = uieditfield(app.CurvedLayerSettingsPanel, 'numeric');
            app.SliceThicknessEditField.Value = 1;
            app.CurvedLayerSliceButton = uibutton(app.CurvedLayerSettingsPanel, 'push');
            app.CurvedLayerSliceButton.Text = 'Curved Layer Slice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end